classdef real_data_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        CheckBox  matlab.ui.control.CheckBox
        TextArea  matlab.ui.control.TextArea
        UITable   matlab.ui.control.Table
        UIAxes    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %import data from excel sheet 
            t = readtable("SleepTable.xlsx","ReadRowNames",false);
            vars = {'TotSleepTime','CORT_BL','CORT_SD'};
            t = t(1:18,vars);

            % calculate change in cortisol levels
            % DELTA_CORT = zeros(height(t),1);
            bl_array = t.CORT_BL; %zeros(height(t),1);
            sd_array = t.CORT_SD; %zeros(height(t),1);
            % bl_array = t(1:18,'CORT_BL');
            % sd_array = t(1:18,'CORT_SD');
            DELTA_CORT = bl_array - sd_array;
            % t.DELTA_CORT = app.UITable.Data.CORT_BL - app.UITable.Data.CORT_SD;
            t.('DELTA_CORT') = DELTA_CORT; %(t((1:18),CORT_BL) - t((1:18),CORT_SD));

            
            % sort data by age
            t = sortrows(t,"TotSleepTime");

            % display data in table
            app.UITable.Data = t;
            
            % store age vs sleep data in plot
            x1 = app.UITable.Data.TotSleepTime;
            y1 = app.UITable.Data.DELTA_CORT;
            plot(app.UIAxes,x1,y1,"o");
        end

        % Value changed function: TextArea
        function display_text(app, event)
           app.TextArea.Value = 'This study displays how sleep deprivation can lead to elevated cortisol levels. The cortisol levels measured the morning after subjects with no sleep compared to subjects who had normal sleep, were much lower. This is due to a variety of reasons, such as the Hypothalamic-Pituitary-Adrenal (HPA) Axis Response. The study that we found discusses how the HPA axis is involved in cortisol secretion. Sleep deprivation can activate the HPA axis which can cause an initial surge in cortical levels during the night time. However, this increase in cortisol levels can desensitize the HPA axis, resulting in blunted cortisol responses, and therefore lower cortisol levels in the morning. This dysregulation in cortisol secretion can have profound effects on various physiological processes, including immune function, metabolism, and brain function.';
            
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if value 
                app.TextArea.Value = 'This study displays how sleep deprivation can lead to elevated cortisol levels. The cortisol levels measured the morning after subjects with no sleep compared to subjects who had normal sleep, were much lower. This is due to a variety of reasons, such as the Hypothalamic-Pituitary-Adrenal (HPA) Axis Response. The study that we found discusses how the HPA axis is involved in cortisol secretion. Sleep deprivation can activate the HPA axis which can cause an initial surge in cortical levels during the night time. However, this increase in cortisol levels can desensitize the HPA axis, resulting in blunted cortisol responses, and therefore lower cortisol levels in the morning. This dysregulation in cortisol secretion can have profound effects on various physiological processes, including immune function, metabolism, and brain function.';
            else
                app.TextArea.Value = '';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Baseline Sleep'; 'Cortisol (BL)'; 'Cortisol (SD)'; '∆Cortisol'};
            app.UITable.ColumnRearrangeable = 'on';
            app.UITable.RowName = {};
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @display_text, true);
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = real_data_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end